classdef VideoAnnotating < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        GridLayout                  matlab.ui.container.GridLayout
        LeftPanel                   matlab.ui.container.Panel
        GridLayout5                 matlab.ui.container.GridLayout
        GridLayout10                matlab.ui.container.GridLayout
        Load                        matlab.ui.control.Button
        RestoreButton               matlab.ui.control.Button
        LastButton                  matlab.ui.control.Button
        SaveNextButton              matlab.ui.control.Button
        GridLayout9                 matlab.ui.container.GridLayout
        videostate                  matlab.ui.control.Label
        VideoNameTextArea           matlab.ui.control.TextArea
        VideoInformationLabel       matlab.ui.control.Label
        GridLayout18                matlab.ui.container.GridLayout
        PressshiftaDropDown         matlab.ui.control.DropDown
        PressshiftaDropDownLabel    matlab.ui.control.Label
        HoldshiftsDropDown          matlab.ui.control.DropDown
        HoldshiftsDropDownLabel     matlab.ui.control.Label
        ReleaseshiftdDropDown       matlab.ui.control.DropDown
        ReleaseshiftdDropDownLabel  matlab.ui.control.Label
        DateTextAreaLabel           matlab.ui.control.Label
        DateTextArea                matlab.ui.control.TextArea
        PerformanceLabel            matlab.ui.control.Label
        PerformanceDropDown         matlab.ui.control.DropDown
        RTTextAreaLabel             matlab.ui.control.Label
        RTTextArea                  matlab.ui.control.TextArea
        GridLayout6                 matlab.ui.container.GridLayout
        ReleaseOnsetTextArea        matlab.ui.control.TextArea
        ReleaseOnsetshiftcButton    matlab.ui.control.Button
        TouchTextArea               matlab.ui.control.TextArea
        TouchshiftxButton           matlab.ui.control.Button
        FlexTextArea                matlab.ui.control.TextArea
        FlexshiftzButton            matlab.ui.control.Button
        RightPanel                  matlab.ui.container.Panel
        GridLayout2                 matlab.ui.container.GridLayout
        GridLayout3                 matlab.ui.container.GridLayout
        frameSlider                 matlab.ui.control.Slider
        FrameLabel                  matlab.ui.control.Label
        GridLayout4                 matlab.ui.container.GridLayout
        FrameMinusButton            matlab.ui.control.Button
        PlayButton                  matlab.ui.control.Button
        FramePlusButton             matlab.ui.control.Button
        FrameMinus10Button          matlab.ui.control.Button
        FramePlus10Button           matlab.ui.control.Button
        UIAxes                      matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    properties (Access = public)
        index_frame % Description
        vidObj
        playvideo = false;
        path
        filename
        index_current
        vidframes
        VidMeta
        b
        ratname
        file
        mfile
        uivideo
        playInterval = 1/50;
    end
    
    methods (Access = private)
        
        function refreshFrame(app)
%             a = tic;
            method = 'refreshdata';
            switch method
                case 'resize'
                    ax_WdivH = app.UIAxes.Position(3)/app.UIAxes.Position(4);
                    vid_WdivH = size(app.vidframes,2)/size(app.vidframes,1);
                    cla(app.UIAxes);
%                     set(app.UIAxes,...
%                         'xtick',[],'XTickLabel',{'[]'},...
%                         'ytick',[],'YTickLabel',{'[]'})
                    app.UIAxes.Toolbar.Visible = 'off';
                    set(app.UIAxes,'xlim',[0 1],'ylim',[0 1],'ydir','reverse');
                    if vid_WdivH>ax_WdivH
                        app.uivideo = imagesc(app.UIAxes,...
                            'XData',[0 app.UIAxes.XLim(2)],...
                            'YData',[app.UIAxes.YLim(2)./2-(app.UIAxes.YLim(2).*ax_WdivH./vid_WdivH)./2,...
                            app.UIAxes.YLim(2)./2+(app.UIAxes.YLim(2).*ax_WdivH./vid_WdivH)./2],...
                            'CData',app.vidframes(:,:,:,app.index_frame),[0 255]);
                    else
                        app.uivideo = imagesc(app.UIAxes,...
                            'XData',[app.UIAxes.XLim(2)./2-(app.UIAxes.XLim(2)./ax_WdivH.*vid_WdivH)./2,...
                            app.UIAxes.XLim(2)./2+(app.UIAxes.XLim(2)./ax_WdivH.*vid_WdivH)./2],...
                            'YData',[0 app.UIAxes.YLim(2)],...
                            'CData',app.vidframes(:,:,:,app.index_frame),[0 255]);
                    end
                case 'refreshdata'
                    ax_WdivH = app.UIAxes.Position(3)/app.UIAxes.Position(4);
                    vid_WdivH = size(app.vidframes,2)/size(app.vidframes,1);
                    if vid_WdivH>ax_WdivH
                        xdata = [0 1];
                        ydata = [1./2-(1.*ax_WdivH./vid_WdivH)./2,...
                            1./2+(1.*ax_WdivH./vid_WdivH)./2];
                    else
                        xdata = [1./2-(1./ax_WdivH.*vid_WdivH)./2,...
                            1./2+(1./ax_WdivH.*vid_WdivH)./2];
                        ydata = [0 1];
                    end
                    cdata = app.vidframes(:,:,:,app.index_frame);
                    if isempty(app.uivideo)
                        set(app.UIAxes,'xlim',[0 1],'ylim',[0 1],'ydir','reverse',...
                            'XLimMode','manual','YLimMode','manual','CLimMode','manual');
                        app.UIAxes.Toolbar.Visible = 'off';
                        app.uivideo = imagesc(app.UIAxes,...
                            'XData',xdata,'YData',ydata,'CData',cdata,[0 255]);
                    else
                        app.uivideo.XData = xdata;
                        app.uivideo.YData = ydata;
                        app.uivideo.CData = cdata;
                    end
                case 'old'
                    f=imagesc(app.UIAxes,app.vidframes(:,:,:,app.index_frame));
                    set(f,'XData',[0 f.XData(2)]);
            end
            drawnow;
%             toc(a)
        end
        
        function loadVidMeta(app)
            [~,purefilename] = fileparts(app.file);
            load(fullfile(app.path,[purefilename,'.mat']),'-mat','VidMeta');
            app.VidMeta=VidMeta;
            app.PerformanceDropDown.Value=app.VidMeta.Performance;
        end
        
        function switchEventVideo(app,pace)
            idx_tarVideo = app.index_current + pace;
            if idx_tarVideo>=1 && idx_tarVideo<=length(app.filename)
                app.index_current=idx_tarVideo;
                initCurrentVideo(app);
                switch class(app.b)
                    case 'struct'
                        app.RTTextArea.Value={num2str((app.b.ReleaseTime(app.VidMeta.EventIndex)...
                            -app.b.PressTime(app.VidMeta.EventIndex))*1000-app.b.FPs(app.VidMeta.EventIndex))};
                    case 'table'
                        app.RTTextArea.Value={num2str(app.b.HT(app.VidMeta.EventIndex).*1000-app.b.FP(app.VidMeta.EventIndex).*1000)};
                end
                loadAnno(app);
                app.vidframes = read(app.vidObj,[1 app.vidObj.NumFrames]);
                refreshFrame(app);
            elseif idx_tarVideo<1
                app.videostate.Text='THIS IS THE FIRST ONE';
            else
                app.videostate.Text='THIS IS THE LAST ONE';
            end
        end
        
        function loadAnno(app)
            if isfile(fullfile(app.path,'VideoData',[app.VideoNameTextArea.Value{1},'Annotated.mat']))
                load(fullfile(app.path,'VideoData',[app.VideoNameTextArea.Value{1},'Annotated.mat']),'-mat','VidAnno');
                app.RTTextArea.Value={num2str(VidAnno.RT)};
                app.PerformanceDropDown.Value=VidAnno.Performance;
                
                app.PressshiftaDropDown.Value=VidAnno.Press;
                app.HoldshiftsDropDown.Value=VidAnno.Hold;
                app.ReleaseshiftdDropDown.Value=VidAnno.Release;
                if isfield(VidAnno,'Flex')
                    app.FlexTextArea.Value={num2str(VidAnno.Flex)};
                end
                if isfield(VidAnno,'Touch')
                    app.TouchTextArea.Value={num2str(VidAnno.Touch)};
                end
                if isfield(VidAnno,'ReleaseOnset')
                    app.ReleaseOnsetTextArea.Value={num2str(VidAnno.ReleaseOnset)};
                end
                app.videostate.Text='THIS VIDEO WAS ANALYSED';
            end
        end
        
        function getBehaviorData(app,videodate)
            [filem,pathm] = uigetfile({'B_*.mat;bmixed*.mat','ProcessedData';...
                '*Subject*.txt;*DSRT*.mat','UnprocessedData'},...
                ['Select ',videodate,' behavior data'],...
                fullfile(app.path,['..',filesep],['..',filesep]),'MultiSelect','on');% med or bpod data
            if ~iscell(filem)
                [~,expName,dataext] = fileparts(filem);
            else
                [~,expName,dataext] = fileparts(filem{1});
            end
            switch dataext
                case '.txt' % Med unprocessed data
                    app.b=track_training_progress_advanced(fullfile(pathm,filem));
                    app.RTTextArea.Value={num2str((app.b.ReleaseTime(app.VidMeta.EventIndex)-...
                        app.b.PressTime(app.VidMeta.EventIndex))*1000-app.b.FPs(app.VidMeta.EventIndex))};
                case '.mat'
                    bData = load(fullfile(pathm,[expName,dataext]));
                    if contains(expName,'B_') && ~isfield(bData,'bt') % Med processed data
                        app.b = bData.b;
                        app.RTTextArea.Value={num2str((app.b.ReleaseTime(app.VidMeta.EventIndex)-...
                            app.b.PressTime(app.VidMeta.EventIndex))*1000-app.b.FPs(app.VidMeta.EventIndex))};
                    else % Bpod data
                        cfilem = cellstr(filem);
                        btAll = cell(1,length(cfilem));
                        if ~contains(expName,'B_') && ~contains(expName,'bmixed') % Bpod unprocessed data
                            for i=1:length(cfilem)
                                btAll{i} = DSRT_DataExtract_Block(fullfile(pathm,cfilem{i}),false);
                            end
                        else % Bpod processed data
                            for i=1:length(cfilem)
                                bData = load(fullfile(pathm,cfilem{i}));
                                btAll{i} = bData.bt;
                            end
                        end
                        btAll = DSRT_DataMerge_Block(btAll,2); % if choose multiple files, merge it
                        app.b = btAll{1};
                        app.RTTextArea.Value={num2str(app.b.HT(app.VidMeta.EventIndex).*1000-app.b.FP(app.VidMeta.EventIndex).*1000)};
                    end
            end
        end
        
        function initCurrentVideo(app)
            app.file=char(app.filename(app.index_current));
            [~,purevidname] = fileparts(app.file);
            app.VideoNameTextArea.Value = {purevidname};
            app.index_frame=1;
            
            app.UIAxes.Visible = 'off';
            app.vidObj = VideoReader(fullfile(app.path,app.file));
            app.frameSlider.Limits=[1 app.vidObj.NumFrames];
            app.frameSlider.Value=1;
            app.RTTextArea.Value={''};
            app.FlexTextArea.Value={''};
            app.TouchTextArea.Value={''};
            app.ReleaseOnsetTextArea.Value={''};
            app.videostate.Text='';
            
            loadVidMeta(app);
        end
        
        function switchVideoFrame(app,pace)
            try
                app.index_frame=min(...
                    max(app.index_frame+pace,app.frameSlider.Limits(1)),...
                    app.frameSlider.Limits(2));
                app.frameSlider.Value=app.index_frame;
                refreshFrame(app);
                app.playvideo = false;
            catch ME
                rethrow(ME);
            end
        end
        
        function saveAnno(app)
            app.VideoNameTextArea.Value = app.VideoNameTextArea.Value{1};
            % Save Annotation
            VidAnno=struct;
            VidAnno.RatName=app.ratname;
            VidAnno.SessionDate=app.DateTextArea.Value{1};
            VidAnno.PressIndex=app.VidMeta.EventIndex;
            VidAnno.PressTime=app.VidMeta.EventTime;
            VidAnno.Press=app.PressshiftaDropDown.Value;
            VidAnno.Hold=app.HoldshiftsDropDown.Value;
            VidAnno.Release=app.ReleaseshiftdDropDown.Value;
            VidAnno.Performance=app.PerformanceDropDown.Value;
            VidAnno.RT=str2double(app.RTTextArea.Value{1});
            VidAnno.Flex=str2double(app.FlexTextArea.Value{1});
            VidAnno.Touch=str2double(app.TouchTextArea.Value{1});
            VidAnno.ReleaseOnset=str2double(app.ReleaseOnsetTextArea.Value{1});
            switch class(app.b)
                case 'struct'
                    VidAnno.FP=app.b.FPs(app.VidMeta.EventIndex);
                case 'table'
                    VidAnno.FP=app.b.FP(app.VidMeta.EventIndex);
            end
            if ~isfolder(fullfile(app.path,'VideoData'))
                mkdir(fullfile(app.path,'VideoData'));
            end
            save(fullfile(app.path,'VideoData',[app.VideoNameTextArea.Value{1},'Annotated']),'VidAnno');
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.mfile = mfilename('fullpath');
            set(app.UIFigure,'renderer','opengl');
        end

        % Button pushed function: Load
        function loadPushed(app, event)
            try
                % Select the videos of one day, then choose a video to
                % process from it
                [exmFile,app.path] = uigetfile('*.avi;*.mp4',...
                    'labeled video');% labeled video
                index=find(exmFile=='_',1,'first');
                videodate=exmFile(index+1:index+8);
                app.ratname=exmFile(1:index-1);
                app.filename=string(arrayfun(@(x)x.name, dir(fullfile(app.path,['*',videodate,'*.avi'])), 'UniformOutput', false));
                app.DateTextArea.Value={videodate};
                option=uiconfirm(app.UIFigure,'Start from this video or the first?','Confirm order','Options',{'This','First'},'DefaultOption',2);
                if strcmp(option,'This')
                    app.index_current=find(contains(app.filename,exmFile),1);
                else
                    app.index_current=1;
                end
                % Initate the video (the Nth video of that day)
                initCurrentVideo(app);
                getBehaviorData(app,videodate);
                loadAnno(app);
                
                app.vidframes = read(app.vidObj,[1 app.vidObj.NumFrames]);
                refreshFrame(app);
            catch ME
                switch ME.identifier
                    case 'MATLAB:FileManip:NullCharacterInName'
                        uialert(app.UIFigure,'Please select the video to be analysed','Cannot find the video');
                    case 'MATLAB:fileparts:MustBeChar'
                        uialert(app.UIFigure,'Please select the behavior data file','Cannot find behavior data');
                    otherwise
                        rethrow(ME);
                end
            end
        end

        % Button pushed function: RestoreButton
        function RestoreButtonPushed(app, event)
            try
                load(fullfile(fileparts(app.mfile),'cache','last_cache.mat'),'cache');
                app.path=cache.path;
                app.ratname=cache.name;
                app.filename=cache.filename;
                app.DateTextArea.Value={cache.date};
                app.index_current=cache.index_current;
                
                initCurrentVideo(app);
                getBehaviorData(app,char(cache.date));
                loadAnno(app);
                
                app.vidframes = read(app.vidObj,[1 app.vidObj.NumFrames]);
                refreshFrame(app);
            catch ME
                switch ME.identifier
                    case 'MATLAB:fileparts:MustBeChar'
                        uialert(app.UIFigure,'Please select the behavior data file','Cannot find behavior data');
                    otherwise
                        rethrow(ME);
                end
            end
        end

        % Button pushed function: LastButton
        function LastButtonPushed(app, event)
            switchEventVideo(app,-1);
        end

        % Button pushed function: SaveNextButton
        function SaveNextButtonPushed(app, event)
            saveAnno(app);
            % Save current app status
            if app.index_current ~= length(app.filename)
                if ~isfolder(fullfile(fileparts(app.mfile),'cache'))
                    mkdir(fullfile(fileparts(app.mfile),'cache'));
                end
                cache.savetime=datestr(now,'yyyymmdd_HH_MM_SS');
                cache.path=app.path;
                cache.name=app.ratname;
                cache.filename=app.filename;
                cache.date=app.DateTextArea.Value{1}; 
                cache.file=char(app.filename(app.index_current+1));
                cache.index_current=app.index_current+1;
                save(fullfile(fileparts(app.mfile),'cache','last_cache.mat'),'cache');
            end
            %next
            switchEventVideo(app,1);
        end

        % Button pushed function: FlexshiftzButton
        function FlexshiftzButtonPushed(app, event)
            app.FlexTextArea.Value={num2str(app.VidMeta.FrameTimesB(app.index_frame)-app.VidMeta.EventTime)};
        end

        % Button pushed function: TouchshiftxButton
        function TouchshiftxButtonPushed(app, event)
            app.TouchTextArea.Value={num2str(app.VidMeta.FrameTimesB(app.index_frame)-app.VidMeta.EventTime)};
        end

        % Button pushed function: ReleaseOnsetshiftcButton
        function ReleaseOnsetshiftcButtonPushed(app, event)
            app.ReleaseOnsetTextArea.Value={num2str(app.VidMeta.FrameTimesB(app.index_frame)-app.VidMeta.EventTime)};
        end

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)
            if app.playvideo
                app.playvideo = false;
                app.PlayButton.Text = 'Play';
                return;
            end
            app.playvideo = true;
            app.PlayButton.Text = 'Pause';
            if app.index_frame==app.vidObj.NumFrames
                app.index_frame = 0;
            end
            while app.playvideo
                a = tic;
                app.index_frame=min(app.index_frame+1,app.vidObj.NumFrames);
                app.frameSlider.Value=app.index_frame;
                refreshFrame(app);
                while toc(a)<app.playInterval
                    if app.playvideo==false
                        app.PlayButton.Text = 'Play';
                        return;
                    end
                end
                if app.index_frame==app.vidObj.NumFrames
                    app.playvideo=false;
                end
            end
            app.PlayButton.Text = 'Play';
        end

        % Callback function
        function PauseButtonPushed(app, event)
            app.playvideo=false;
        end

        % Button pushed function: FrameMinusButton
        function FrameMinusButtonPushed(app, event)
            switchVideoFrame(app,-1);
        end

        % Button pushed function: FramePlusButton
        function FramePlusButtonPushed(app, event)
            switchVideoFrame(app,1);
        end

        % Button pushed function: FrameMinus10Button
        function FrameMinus10ButtonPushed(app, event)
            switchVideoFrame(app,-10);
        end

        % Button pushed function: FramePlus10Button
        function FramePlus10ButtonPushed(app, event)
            switchVideoFrame(app,10);
        end

        % Value changing function: frameSlider
        function frameSliderValueChanging(app, event)
            try
                app.index_frame= round(event.Value);
                app.frameSlider.Value = app.index_frame;
                refreshFrame(app);
            catch ME
                rethrow(ME);
            end
        end

        % Window scroll wheel function: UIFigure
        function UIFigureWindowScrollWheel(app, event)
            try
                verticalScrollCount = event.VerticalScrollCount;
                app.index_frame = max(...
                    min(app.index_frame+verticalScrollCount,app.frameSlider.Limits(2)),...
                    app.frameSlider.Limits(1));
                app.frameSlider.Value=app.index_frame;
                refreshFrame(app);
                app.playvideo = false;
            catch ME
                rethrow(ME);
            end
        end

        % Window key press function: UIFigure
        function UIFigureWindowKeyPress(app, event)
            if isempty(event.Modifier)
                modi='';
            else
                modi=event.Modifier{1};
            end
            key = event.Key;
            switch modi
                case 'shift'
                    switch key
                        case 'a'
                            items = app.PressshiftaDropDown.Items;
                            curIdx = find(ismember(items,app.PressshiftaDropDown.Value));
                            if curIdx+1>length(items)
                                tarIdx = 1;
                            else
                                tarIdx = curIdx+1;
                            end
                            app.PressshiftaDropDown.Value = items{tarIdx};
                        case 's'
                            items = app.HoldshiftsDropDown.Items;
                            curIdx = find(ismember(items,app.HoldshiftsDropDown.Value));
                            if curIdx+1>length(items)
                                tarIdx = 1;
                            else
                                tarIdx = curIdx+1;
                            end
                            app.HoldshiftsDropDown.Value = items{tarIdx};
                        case 'd'
                            items = app.ReleaseshiftdDropDown.Items;
                            curIdx = find(ismember(items,app.ReleaseshiftdDropDown.Value));
                            if curIdx+1>length(items)
                                tarIdx = 1;
                            else
                                tarIdx = curIdx+1;
                            end
                            app.ReleaseshiftdDropDown.Value = items{tarIdx};
                        case 'z'
                            FlexshiftzButtonPushed(app);
                        case 'x'
                            TouchshiftxButtonPushed(app);
                        case 'c'
                            ReleaseOnsetshiftcButtonPushed(app);
                    end
                otherwise
            end
        end

        % Window key release function: UIFigure
        function UIFigureWindowKeyRelease(app, event)
            key = event.Key;
            switch key
                case 'rightarrow'
                    FramePlus10ButtonPushed(app);
                case 'leftarrow'
                    FrameMinus10ButtonPushed(app);
                case 'space'
                    PlayButtonPushed(app);
            end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {750, 750};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {285, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.UIFigure.WindowScrollWheelFcn = createCallbackFcn(app, @UIFigureWindowScrollWheel, true);
            app.UIFigure.WindowKeyPressFcn = createCallbackFcn(app, @UIFigureWindowKeyPress, true);
            app.UIFigure.WindowKeyReleaseFcn = createCallbackFcn(app, @UIFigureWindowKeyRelease, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.GridLayout5 = uigridlayout(app.LeftPanel);
            app.GridLayout10 = uigridlayout(app.GridLayout5);
            app.Load = uibutton(app.GridLayout10, 'push');
            app.Load.ButtonPushedFcn = createCallbackFcn(app, @loadPushed, true);
            app.Load.Text = {'Load'; 'video'};
            app.RestoreButton = uibutton(app.GridLayout10, 'push');
            app.RestoreButton.ButtonPushedFcn = createCallbackFcn(app, @RestoreButtonPushed, true);
            app.RestoreButton.Text = 'Restore';
            app.LastButton = uibutton(app.GridLayout10, 'push');
            app.LastButton.ButtonPushedFcn = createCallbackFcn(app, @LastButtonPushed, true);
            app.LastButton.Text = 'Last';
            app.SaveNextButton = uibutton(app.GridLayout10, 'push');
            app.SaveNextButton.ButtonPushedFcn = createCallbackFcn(app, @SaveNextButtonPushed, true);
            app.SaveNextButton.Text = {'Save'; '&'; 'Next'};
            app.GridLayout9 = uigridlayout(app.GridLayout5);
            app.videostate = uilabel(app.GridLayout9);
            app.videostate.Text = '';
            app.VideoNameTextArea = uitextarea(app.GridLayout9);
            app.VideoNameTextArea.Editable = 'off';
            app.VideoInformationLabel = uilabel(app.GridLayout9);
            app.VideoInformationLabel.Text = 'Video Information';
            app.GridLayout18 = uigridlayout(app.GridLayout5);
            app.PressshiftaDropDown = uidropdown(app.GridLayout18);
            app.PressshiftaDropDown.Items = {'nan', 'L', 'R', 'B'};
            app.PressshiftaDropDown.Value = 'nan';
            app.PressshiftaDropDownLabel = uilabel(app.GridLayout18);
            app.PressshiftaDropDownLabel.Text = {'Press'; '(shift+a)'};
            app.HoldshiftsDropDown = uidropdown(app.GridLayout18);
            app.HoldshiftsDropDown.Items = {'nan', 'L', 'R', 'B'};
            app.HoldshiftsDropDown.Value = 'nan';
            app.HoldshiftsDropDownLabel = uilabel(app.GridLayout18);
            app.HoldshiftsDropDownLabel.Text = {'Hold'; '(shift+s)'};
            app.ReleaseshiftdDropDown = uidropdown(app.GridLayout18);
            app.ReleaseshiftdDropDown.Items = {'nan', 'L', 'R', 'B'};
            app.ReleaseshiftdDropDown.Value = 'nan';
            app.ReleaseshiftdDropDownLabel = uilabel(app.GridLayout18);
            app.ReleaseshiftdDropDownLabel.Text = {'Release'; '(shift+d)'};
            app.DateTextAreaLabel = uilabel(app.GridLayout18);
            app.DateTextAreaLabel.Text = 'Date';
            app.DateTextArea = uitextarea(app.GridLayout18);
            app.PerformanceLabel = uilabel(app.GridLayout18);
            app.PerformanceLabel.Text = {'Performance'; ''};
            app.PerformanceDropDown = uidropdown(app.GridLayout18);
            app.PerformanceDropDown.Items = {'Late', 'Premature', 'Correct'};
            app.PerformanceDropDown.Value = 'Late';
            app.RTTextAreaLabel = uilabel(app.GridLayout18);
            app.RTTextAreaLabel.Text = 'RT';
            app.RTTextArea = uitextarea(app.GridLayout18);
            app.GridLayout6 = uigridlayout(app.GridLayout5);
            app.ReleaseOnsetTextArea = uitextarea(app.GridLayout6);
            app.ReleaseOnsetshiftcButton = uibutton(app.GridLayout6, 'push');
            app.ReleaseOnsetshiftcButton.ButtonPushedFcn = createCallbackFcn(app, @ReleaseOnsetshiftcButtonPushed, true);
            app.ReleaseOnsetshiftcButton.Text = {'ReleaseOnset'; '(shift+c)'};
            app.TouchTextArea = uitextarea(app.GridLayout6);
            app.TouchshiftxButton = uibutton(app.GridLayout6, 'push');
            app.TouchshiftxButton.ButtonPushedFcn = createCallbackFcn(app, @TouchshiftxButtonPushed, true);
            app.TouchshiftxButton.Text = {'Touch'; '(shift+x)'};
            app.FlexTextArea = uitextarea(app.GridLayout6);
            app.FlexshiftzButton = uibutton(app.GridLayout6, 'push');
            app.FlexshiftzButton.ButtonPushedFcn = createCallbackFcn(app, @FlexshiftzButtonPushed, true);
            app.FlexshiftzButton.Text = {'Flex'; '(shift+z)'};
            app.RightPanel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.RightPanel);
            app.GridLayout3 = uigridlayout(app.GridLayout2);
            app.frameSlider = uislider(app.GridLayout3);
            app.frameSlider.ValueChangingFcn = createCallbackFcn(app, @frameSliderValueChanging, true);
            app.FrameLabel = uilabel(app.GridLayout3);
            app.FrameLabel.Text = 'Frame#';
            app.GridLayout4 = uigridlayout(app.GridLayout2);
            app.FrameMinusButton = uibutton(app.GridLayout4, 'push');
            app.FrameMinusButton.ButtonPushedFcn = createCallbackFcn(app, @FrameMinusButtonPushed, true);
            app.FrameMinusButton.Text = 'Frame -1';
            app.PlayButton = uibutton(app.GridLayout4, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Text = 'Play';
            app.FramePlusButton = uibutton(app.GridLayout4, 'push');
            app.FramePlusButton.ButtonPushedFcn = createCallbackFcn(app, @FramePlusButtonPushed, true);
            app.FramePlusButton.Text = 'Frame +1';
            app.FrameMinus10Button = uibutton(app.GridLayout4, 'push');
            app.FrameMinus10Button.ButtonPushedFcn = createCallbackFcn(app, @FrameMinus10ButtonPushed, true);
            app.FrameMinus10Button.Text = 'Frame -10';
            app.FramePlus10Button = uibutton(app.GridLayout4, 'push');
            app.FramePlus10Button.ButtonPushedFcn = createCallbackFcn(app, @FramePlus10ButtonPushed, true);
            app.FramePlus10Button.Text = 'Frame +10';
            app.UIAxes = uiaxes(app.GridLayout2);
            title(app.UIAxes, 'Video')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.Visible = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = VideoAnnotating

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end