classdef VideoCat_AviMed < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        GridLayout                  matlab.ui.container.GridLayout
        GridLayout5                 matlab.ui.container.GridLayout
        ExportFormatDropDown        matlab.ui.control.DropDown
        RunButton                   matlab.ui.control.Button
        RefreshButton               matlab.ui.control.Button
        ResetButton                 matlab.ui.control.Button
        BasicInfoclickbuttontoselectpathPanel  matlab.ui.container.Panel
        GridLayout4                 matlab.ui.container.GridLayout
        DateyyyymmddEditField       matlab.ui.control.NumericEditField
        DateyyyymmddEditFieldLabel  matlab.ui.control.Label
        ANMEditField                matlab.ui.control.EditField
        ANMEditFieldLabel           matlab.ui.control.Label
        workpathTextArea            matlab.ui.control.TextArea
        WorkingpathButton           matlab.ui.control.Button
        SelectthefilestobeanalysedclickbuttontoselectallPanel  matlab.ui.container.Panel
        GridLayout3                 matlab.ui.container.GridLayout
        OriginalTimeFilesButton     matlab.ui.control.Button
        OriginalVideoFilesButton    matlab.ui.control.Button
        OriginalTimeFilesListBox    matlab.ui.control.ListBox
        OriginalVideoFilesListBox   matlab.ui.control.ListBox
    end

    methods (Access = private)
        
        function [sbjname,date,clock] = resolveMedName(~,MedName)
            [~,justname,~] = fileparts(MedName);
            sbjname = justname(strfind(justname,'Subject')+8:end);
            date = strrep(justname(1:10),'-','');
            clock = strrep(justname(12:16),'h','');
        end
        
        function refreshFiles(app)
            wkpath = app.workpathTextArea.Value{1};
            if isfolder(wkpath)
                path_ori = pwd;
                cd(wkpath);
                
                xfiles = dir('Cam*.avi');
                nfiles = length(xfiles);
                AviFiles = cell(nfiles,1); TxtFiles = cell(nfiles,1); IdxFiles = zeros(nfiles,1);
                for i=1:length(xfiles)
                    AviFiles{i} = xfiles(i).name;
                    TxtFiles{i} = strrep(xfiles(i).name,'avi','txt');
                    IdxFiles(i) = xfiles(i).datenum;
                end
                [~,indsort] = sort(IdxFiles);
                AviFiles = AviFiles(indsort);
                TxtFiles = TxtFiles(indsort);
                
                app.OriginalVideoFilesListBox.Items = AviFiles;
                app.OriginalTimeFilesListBox.Items = TxtFiles;
                cd(path_ori);
            else
                app.OriginalVideoFilesListBox.Items = {};
                app.OriginalTimeFilesListBox.Items = {};
            end
            app.OriginalVideoFilesListBox.Value = {};
            app.OriginalTimeFilesListBox.Value = {};
        end
        
        function refreshInfo(app)
            wkpath = app.workpathTextArea.Value{1};
            if isfolder(wkpath)
                path_ori = pwd;
                cd(wkpath);
                MedFiles = arrayfun(@(x)x.name, dir('*Subject*.txt'), 'UniformOutput', false);
                if ~isempty(MedFiles)
                    [sbjname,date,~] = resolveMedName(app,MedFiles{1});
                    app.ANMEditField.Value = sbjname;
                    app.DateyyyymmddEditField.Value = str2double(date);
                else
                    app.ANMEditField.Value = '';
                    app.DateyyyymmddEditField.Value = 19700101;
                end
                cd(path_ori);
            else
                app.ANMEditField.Value = '';
                app.DateyyyymmddEditField.Value = 19700101;
            end
        end
        
        function MergeVideoFiles(app,anm,date,VidFiles,TxtFiles,expFormat)
            %% Initiate
            wkpath = pwd;
            thisFolder = fullfile(wkpath,'VideoData');
            if ~exist(thisFolder, 'dir')
                mkdir(thisFolder);
            end
            
            fig_width = 10; % cm
            fig_sub = 1; % cm, info area height
            
            % fig_num = str2double(datestr(datetime('now'),'mmddHHMMSS'));
            fig_num = 27;
            %% Prepare
            FileInfo = struct;
            FileInfo.Name = anm;
            FileInfo.Date = date;
            FileInfo.VidFiles = VidFiles;
            FileInfo.TxtFiles = TxtFiles;
            
            for i=1:length(VidFiles)
                VidFiles{i} = fullfile(wkpath,VidFiles{i});
                TxtFiles{i} = fullfile(wkpath,TxtFiles{i});
            end
            
            allFrames = 0;
            for i=1:length(VidFiles)
                vidObj = VideoReader(VidFiles{i});
                allFrames = allFrames + vidObj.NumFrames;
            end
            img_height = vidObj.Height;
            img_width = vidObj.Width;
            fRate = vidObj.FrameRate;
            clear vidObj;
            
            FileInfo.FileID         = nan(1,allFrames);
            FileInfo.FrameIdxInFile = nan(1,allFrames);
            FileInfo.FrameIdx       = nan(1,allFrames);
            FileInfo.tframe         = nan(1,allFrames);
            
            %% Make Video
            switch expFormat
                case '.avi'
                    extVid = '.avi';
                    profileVid = 'Motion JPEG AVI';
                    quality = 80;
                case '.mp4'
                    extVid = '.mp4';
                    profileVid = 'MPEG-4';
                    quality = 100;
                otherwise
                    extVid = '.avi';
                    profileVid = 'Motion JPEG AVI';
                    quality = 80; % default
            end
            video_name = [anm,'_',date];
            filename = fullfile(thisFolder,[video_name,extVid]);
            writerObj = VideoWriter(filename,profileVid);
            writerObj.Quality = quality;
            writerObj.FrameRate = fRate;
            open(writerObj);
            
            dbar = uiprogressdlg(app.UIFigure,'Title',video_name,...
                'Message','Time remaining: calculating...',...
                'Cancelable','on','CancelText','Cancel');
            
            hf27 = figure(fig_num); clf(hf27,'reset');
                set(hf27, 'name', 'side view', 'units', 'centimeters',...
                'position', [3 5 fig_width fig_sub+fig_width*img_height/img_width],...
                'PaperPositionMode', 'auto', 'color', 'w', 'renderer', 'opengl');
            ha = axes;
            set(ha, 'units', 'centimeters', 'position', [0 fig_sub fig_width fig_width*img_height/img_width],...
                'nextplot', 'add', 'xlim',[0 img_width], 'ylim', [0 img_height], 'ydir','reverse')
            axis off
            ha2 = axes;
            set(ha2, 'units', 'centimeters', 'position', [0 0 fig_width*img_height/img_width fig_sub],...
                'nextplot', 'add', 'xlim',[0 4],'ylim', [0 2])
            axis off;
            
            idxFrame = 1;
            lenTiming = 100;
            costTime = nan(1,ceil(allFrames/lenTiming));
            for i=1:length(VidFiles)
                if dbar.CancelRequested
                    break;
                end
                fileID = fopen(TxtFiles{i}, 'r');
                formatSpec = '%f' ;
                NumOuts = fscanf(fileID, formatSpec); % this contains frame time (in ms) and frame index    
                fclose(fileID);
                
                ind_brk = find(NumOuts ==0);    
                FrameTs = NumOuts(1:ind_brk-1); % frame times
            %     FrameIdx = NumOuts(ind_brk+1:end); % frame idx
                filename = VidFiles{i};
                vidObj = VideoReader(filename);
                
                if idxFrame==1
                    tframe_start = FrameTs(1);
                end
                
                for j=1:vidObj.NumFrames
                    if dbar.CancelRequested
                        break;
                    end
                    if idxFrame==1
                        wtic = tic;
                    elseif rem(idxFrame,lenTiming)==0
                        costTime(idxFrame/lenTiming) = toc(wtic);
                        remainTimeS = mean(costTime,'omitnan').*sum(isnan(costTime));
                        remainTimeM = ceil(remainTimeS./60);
                        remainTime = [num2str(remainTimeM),' min'];
                        dbar.Value = idxFrame/allFrames;
                        dbar.Message = ['Time remaining: ',remainTime];
                        wtic = tic;
                    end
                    
                    FileInfo.FrameIdx(idxFrame) = idxFrame;
                    tframe = FrameTs(j)-tframe_start;
                    FileInfo.tframe(idxFrame) = tframe;
                    FileInfo.FileID(idxFrame) = i;
                    FileInfo.FrameIdxInFile(idxFrame) = j;
                    
                    thisFrame = read(vidObj,j);
                    if idxFrame==1
                        %%
                        img = imagesc(ha, thisFrame, [0 255]);
                        colormap('gray');
                        
                        text(ha2,0.6,1.5,anm,'fontsize',10,'color','k','fontweight','bold','fontname','Arial');
                        text(ha2,2.5,1.5,date,'fontsize',10,'color','k','fontweight','bold','fontname','Arial');
                        text(ha2,0.6,0.6,'Frame#','fontsize',10,'color','k','fontweight','bold','fontname','Arial');
                        h_idx = text(ha2,1.3,0.6,num2str(idxFrame),'fontsize',10,'color','k','fontweight','bold','fontname','Arial');
                        text(ha2,2.5,0.6,'tFrame:','fontsize',10,'color','k','fontweight','bold','fontname','Arial');
                        h_tframe = text(ha2,3.3,0.6,[num2str(tframe),' ms'],'fontsize',10,'color','k','fontweight','bold','fontname','Arial');
                    else
                        img.CData = thisFrame;
                        h_idx.String = num2str(idxFrame);
                        h_tframe.String = [num2str(tframe),' ms'];
                        drawnow;
                    end
%                     F = getframe(hf27);writeVideo(writerObj,rgb2gray(F.cdata));
                    writeVideo(writerObj,getframe(hf27));
                    idxFrame = idxFrame + 1;
                end
            end
            %% Save Video
            close(writerObj);
            clear writerObj;
            
            Name = char(repelem(string(FileInfo.Name),allFrames)');
            Date = char(repelem(string(FileInfo.Date),allFrames)');
            FileInfoTable = table(Name,Date,FileInfo.FileID',FileInfo.FrameIdxInFile',...
                FileInfo.FrameIdx',FileInfo.tframe');
            FileInfoTable.Properties.VariableNames = {'Name','Date','FileID','FrameIdxInFile'...
                'FrameIdx','tframe'};

            FileInfoName = fullfile(thisFolder,[video_name,'.mat']);
            save(FileInfoName,'FileInfo','FileInfoTable');

            close(dbar);
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
%             mfile = mfilename('fullpath');
%             [mpath,~,~] = fileparts(mfile);
%             addpath(mpath,fullfile(mpath,'Functions'));
            
            wkpath = pwd;
            app.workpathTextArea.Value = {wkpath};
            refreshInfo(app);
            refreshFiles(app);
        end

        % Button pushed function: WorkingpathButton
        function WorkingpathButtonPushed(app, event)
            value = cellstr(app.workpathTextArea.Value);
            if length(value)==1 && isfolder(value{1})
                path = value{1};
            else
                path = []; %i.e., pwd
            end
            result = uigetdir(path,'Select the working path');
            if result~=0
                app.workpathTextArea.Value = result;
                cd(result);
                refreshInfo(app);
                refreshFiles(app);
            end
        end

        % Button pushed function: OriginalVideoFilesButton
        function OriginalVideoFilesButtonPushed(app, event)
            if isequal(app.OriginalVideoFilesListBox.Value,app.OriginalVideoFilesListBox.Items)
                app.OriginalVideoFilesListBox.Value = {};
            else
                app.OriginalVideoFilesListBox.Value = app.OriginalVideoFilesListBox.Items;
            end
        end

        % Button pushed function: OriginalTimeFilesButton
        function OriginalTimeFilesButtonPushed(app, event)
            if isequal(app.OriginalTimeFilesListBox.Value,app.OriginalTimeFilesListBox.Items)
                app.OriginalTimeFilesListBox.Value = {};
            else
                app.OriginalTimeFilesListBox.Value = app.OriginalTimeFilesListBox.Items;
            end
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.workpathTextArea.Value = {''};
            refreshInfo(app);
            refreshFiles(app);
        end

        % Button pushed function: RefreshButton
        function RefreshButtonPushed(app, event)
            refreshInfo(app);
            refreshFiles(app);
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            wkpath = app.workpathTextArea.Value{1};
            anm = app.ANMEditField.Value;
            date = num2str(app.DateyyyymmddEditField.Value);
            vidName = app.OriginalVideoFilesListBox.Value;
            txtName = app.OriginalTimeFilesListBox.Value;
            expFormat = app.ExportFormatDropDown.Value;
            cd(wkpath);
            MergeVideoFiles(app,anm,date,vidName,txtName,expFormat);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.SelectthefilestobeanalysedclickbuttontoselectallPanel = uipanel(app.GridLayout);
            app.SelectthefilestobeanalysedclickbuttontoselectallPanel.Title = 'Select the files to be analysed (click button to select all)';
            app.GridLayout3 = uigridlayout(app.SelectthefilestobeanalysedclickbuttontoselectallPanel);
            app.OriginalVideoFilesListBox = uilistbox(app.GridLayout3);
            app.OriginalVideoFilesListBox.Items = {'Scene1.avi', 'Scene2.avi', 'Scene3.avi', 'Scene4.avi'};
            app.OriginalVideoFilesListBox.Multiselect = 'on';
            app.OriginalVideoFilesListBox.Value = {};
            app.OriginalTimeFilesListBox = uilistbox(app.GridLayout3);
            app.OriginalTimeFilesListBox.Items = {'Clapper1.txt', 'Clapper2.txt', 'Clapper3.txt', 'Clapper4.txt'};
            app.OriginalTimeFilesListBox.Multiselect = 'on';
            app.OriginalTimeFilesListBox.Value = {};
            app.OriginalVideoFilesButton = uibutton(app.GridLayout3, 'push');
            app.OriginalVideoFilesButton.ButtonPushedFcn = createCallbackFcn(app, @OriginalVideoFilesButtonPushed, true);
            app.OriginalVideoFilesButton.Text = {'Original '; 'Video Files'};
            app.OriginalTimeFilesButton = uibutton(app.GridLayout3, 'push');
            app.OriginalTimeFilesButton.ButtonPushedFcn = createCallbackFcn(app, @OriginalTimeFilesButtonPushed, true);
            app.OriginalTimeFilesButton.Text = {'Original '; 'Time Files'};
            app.BasicInfoclickbuttontoselectpathPanel = uipanel(app.GridLayout);
            app.BasicInfoclickbuttontoselectpathPanel.Title = 'Basic Info (click button to select path)';
            app.GridLayout4 = uigridlayout(app.BasicInfoclickbuttontoselectpathPanel);
            app.WorkingpathButton = uibutton(app.GridLayout4, 'push');
            app.WorkingpathButton.ButtonPushedFcn = createCallbackFcn(app, @WorkingpathButtonPushed, true);
            app.WorkingpathButton.Text = 'Working path';
            app.workpathTextArea = uitextarea(app.GridLayout4);
            app.workpathTextArea.Value = {'C:\Users\KWW\Compositions\AFJJ\Yuddy\19600416'};
            app.ANMEditFieldLabel = uilabel(app.GridLayout4);
            app.ANMEditFieldLabel.Text = 'ANM';
            app.ANMEditField = uieditfield(app.GridLayout4, 'text');
            app.ANMEditField.Value = 'Yuddy';
            app.DateyyyymmddEditFieldLabel = uilabel(app.GridLayout4);
            app.DateyyyymmddEditFieldLabel.Text = {'Date'; '(yyyymmdd)'};
            app.DateyyyymmddEditField = uieditfield(app.GridLayout4, 'numeric');
            app.DateyyyymmddEditField.ValueDisplayFormat = '%.0f';
            app.DateyyyymmddEditField.Value = 19600416;
            app.GridLayout5 = uigridlayout(app.GridLayout);
            app.ResetButton = uibutton(app.GridLayout5, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.RefreshButton = uibutton(app.GridLayout5, 'push');
            app.RefreshButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshButtonPushed, true);
            app.RefreshButton.Text = 'Refresh';
            app.RunButton = uibutton(app.GridLayout5, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.ExportFormatDropDown = uidropdown(app.GridLayout5);
            app.ExportFormatDropDown.Items = {'-- Select the format of exported video', '.avi', '.mp4'};
            app.ExportFormatDropDown.Value = '.mp4';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = VideoCat_AviMed

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end